classdef mammology_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AnswerLabel                     matlab.ui.control.Label
        ShowanswerButton                matlab.ui.control.Button
        username_confirm                matlab.ui.control.Label
        second_time_text                matlab.ui.control.Label
        LoginButton                     matlab.ui.control.Button
        UsernameEditField               matlab.ui.control.EditField
        UsernameEditFieldLabel          matlab.ui.control.Label
        FinishExamButton                matlab.ui.control.Button
        ConfirmButton                   matlab.ui.control.Button
        YourresultwilldisplayhereLabel  matlab.ui.control.Label
        PleaseloginbeforestartingLabel  matlab.ui.control.Label
        PreviousImageButton             matlab.ui.control.Button
        NextImageButton                 matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            %imports image function mapping
            global funcStruct
            run('function_list.m');
            funcStruct = funcStruct;
            
            %extracts a category count
            global categoryMap
            categoryMap = containers.Map();
            keySet = keys(funcStruct);
            for i = 1:length(keySet)
                k = keySet{i};
                v = funcStruct(k);
                categoryMap(v{3}) = 0;
            end


            %Extracts list of image names
            files = dir('Images\');
            FileNames = {files.name};
            global FileNameMat
            FileNameMat = strings;
            for i=1:length(FileNames)
                FileNameMat(i)=append('Images/',string(FileNames(i)));
            end
            FileNameMat(1) = [];
            FileNameMat(1) = [];


            %Creates a table of user information to be stored
            %Stored in order: username, date, totalscore, image 1 score, image 2, ..., image n score
            %images are ordered as they are in FileNameMat
            %user data will be initialized after log-in 
            global user_data
            global login_indicator
            login_indicator = 0;
            user_data = {'username', 'date','total_score'};  % Initialize user_data with column names
            user_data = [user_data, FileNameMat];  % Append image names as headers
            user_data = [user_data;zeros(1,length(user_data))];
            user_data(end,2) = datetime('today');
            
            global user_data_adjustment
            user_data_adjustment = 3;
            global quiz_length 
            quiz_length= length(user_data)-user_data_adjustment;

            % Initiates photo and axis
            Z = imread(FileNameMat(1));
            global image_index
            image_index = 1;
            X = linspace(0,20*pi,size(Z,2)); % Important: number of cols of Z
            Y = linspace(0,20*pi,size(Z,1)); % Important: number of rows of Z
            I = imagesc(app.UIAxes,X,Y,Z);
            app.UIAxes.Colormap = jet(100); % Any number of colors should work
            axis(app.UIAxes,'tight')
            % colorbar(app.UIAxes)


            % Turn on datacursor mode in the UIFigure
            dcm = datacursormode(app.UIFigure);
            dcm.Enable = 'on';
            % Define a custom UpdateFcn function
            dcm.UpdateFcn = @(hObj,event,ax)localDcmFcn(hObj,event,app.UIAxes);
            function txt = localDcmFcn(~,event,ax)
                % find indices to closest x and y data values.
            [~, xidx] = min(abs(event.Target.XData - event.Position(1)));
            [~, yidx] = min(abs(event.Target.YData - event.Position(2)));
            colorbarValue = event.Target.CData(yidx, xidx); 
            colormapIdx = round((colorbarValue - ax.CLim(1))/(ax.CLim(2)-ax.CLim(1)) * (size(ax.Colormap,1)-1) +1);
            txt = sprintf('[X,Y]: [%.2g, %.2g]', ...
                event.Position);
            global pos
            pos = event.Position;
            end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
            % Get the coordinates of the mouse click relative to the axes
            [x,y] = ginput(1);
            % Display the coordinates in the Command Window
            set(app.YourresultwilldisplayhereLabel, 'Text', ['X: ', num2str(x), ', Y: ', num2str(y)]);  
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            global image_index
            global FileNameMat
            global pos
            global user_data
            global user_data_adjustment
            global categoryMap
            user_data_adjustment = 3;
            %set(app.YourresultwilldisplayhereLabel, 'Text', ['X: ', num2str(pos(1,1)), ', Y: ', num2str(pos(1,2))]);
            global funcStruct
            global login_indicator
                mapping = funcStruct(FileNameMat(image_index));
            conditional = mapping{1};
            isCorrect = conditional(pos(1,1), pos(1,2));
            if login_indicator == 1
                if user_data(end,image_index+user_data_adjustment) == '0'
                    set(app.second_time_text, 'Text', '');
                    if isCorrect
                        set(app.YourresultwilldisplayhereLabel,'Text', 'Nice!');
                        set(app.YourresultwilldisplayhereLabel, 'FontColor', '0.47,0.67,0.19');
                        user_data(end,2) = user_data(end,2) + 1;
                        categoryMap(mapping{3}) = categoryMap(mapping{3}) + 1;
                    else
                        set(app.YourresultwilldisplayhereLabel, 'Text', 'Nope!');
                        set(app.YourresultwilldisplayhereLabel, 'FontColor', '1.00,0.00,0.00');
                    end
                    % Update the user_data table to store the result for this image
                    user_data(end, image_index + user_data_adjustment) = mapping{3} + " " + string(isCorrect);  % Store the click result (1 or 0) 


                else
                    set(app.second_time_text, 'Text', 'You have already answered this question');
                end
            else
                beep on
                beep
                set(app.PleaseloginbeforestartingLabel, 'BackgroundColor', '1.00,0.00,0.00');
            end


        end

        % Button pushed function: NextImageButton
        function NextImage(app, event)
            global image_index
            global FileNameMat
            global user_data
            global user_data_adjustment
            global quiz_length
            image_index = image_index+1;
            if image_index > quiz_length
                image_index = image_index -1;
                beep on
                beep
                set(app.PleaseloginbeforestartingLabel, 'Text', 'You are at the end of the assessment.');
            else
                Z = imread(FileNameMat(image_index));
                X = linspace(0,20*pi,size(Z,2)); % Important: number of cols of Z
                Y = linspace(0,20*pi,size(Z,1)); % Important: number of rows of Z
                I = imagesc(app.UIAxes,X,Y,Z);
                set(app.PleaseloginbeforestartingLabel, 'Text', 'Click the abnormality, or click benign. ');
                if user_data(end,image_index+user_data_adjustment) == '0'
                    set(app.YourresultwilldisplayhereLabel,'Text', 'Your result will display here');
                    set(app.YourresultwilldisplayhereLabel, 'FontColor', '0.00,0.00,0.00');
                elseif user_data(end,image_index+user_data_adjustment) == 'true'
                    set(app.YourresultwilldisplayhereLabel,'Text', 'Nice!');
                    set(app.YourresultwilldisplayhereLabel, 'FontColor', '0.47,0.67,0.19');
                elseif user_data(end,image_index+user_data_adjustment) == 'false'
                    set(app.YourresultwilldisplayhereLabel,'Text', 'Nope!');
                    set(app.YourresultwilldisplayhereLabel, 'FontColor', '1.00,0.00,0.00');
                end
            end
                
        end

        % Button pushed function: PreviousImageButton
        function PrevImage(app, event)
            global image_index
            global FileNameMat
            global user_data
            global user_data_adjustment
            image_index = image_index-1;
            if image_index < 1
                image_index = image_index +1;
                beep on
                beep
                set(app.PleaseloginbeforestartingLabel, 'Text', 'You are at the beginning of the assessment.');
            else
                Z = imread(FileNameMat(image_index));
                X = linspace(0,20*pi,size(Z,2)); % Important: number of cols of Z
                Y = linspace(0,20*pi,size(Z,1)); % Important: number of rows of Z
                I = imagesc(app.UIAxes,X,Y,Z);
                set(app.PleaseloginbeforestartingLabel, 'Text', 'Click the abnormality, or click benign. ');
                if user_data(end,image_index+user_data_adjustment) == '0'
                    set(app.YourresultwilldisplayhereLabel,'Text', 'Your result will display here');
                    set(app.YourresultwilldisplayhereLabel, 'FontColor', '0.00,0.00,0.00');
                elseif user_data(end,image_index+user_data_adjustment) == 'true'
                    set(app.YourresultwilldisplayhereLabel,'Text', 'Nice!');
                    set(app.YourresultwilldisplayhereLabel, 'FontColor', '0.47,0.67,0.19');
                elseif user_data(end,image_index+user_data_adjustment) == 'false'
                    set(app.YourresultwilldisplayhereLabel,'Text', 'Nope!');
                    set(app.YourresultwilldisplayhereLabel, 'FontColor', '1.00,0.00,0.00');
                end
            end
        end

        % Button pushed function: LoginButton
        function LogIn(app, event)
            global user_data
            user = app.UsernameEditField.Value;
            user_data(2,1)={user};
            set(app.username_confirm, 'Text', strcat(user_data(end,1),' logged in!'))
            set(app.PleaseloginbeforestartingLabel, 'Text', 'Click the abnormality, or click benign. ')
            set(app.PleaseloginbeforestartingLabel, 'BackgroundColor', 'none')
            global login_indicator
            login_indicator = 1;
        end

        % Button pushed function: FinishExamButton
        function FinishExamButtonPushed(app, event)
            global user_data
            global categoryMap
            total = 0;
            for i=4:size(user_data,2)
                if endsWith(user_data(2,i), 'true')
                    total = total + 1;
                end
            end
            user_data(2,3) = total;
            keysList = keys(categoryMap);
            valuesList = values(categoryMap);
            tempMap = containers.Map(keysList, valuesList);
            tempMap('total') = total;

            relative_directory = 'results';
            if ~exist(relative_directory, 'dir')
                mkdir(relative_directory);
            end
            output_file = fullfile(relative_directory, strcat(user_data(2,1), '_results.xls'));
            writematrix(user_data, output_file)
            set(app.PleaseloginbeforestartingLabel, 'Text', 'Exam finished and results saved, you may exit the app. ');
            set(app.PleaseloginbeforestartingLabel, 'BackgroundColor', '0.00,1.00,0.00');
            results_screen(app, tempMap)
        end

        % Button pushed function: ShowanswerButton
        function ShowanswerButtonPushed(app, event)
            global image_index
            global FileNameMat
            global pos
            global user_data
            global user_data_adjustment
            global funcStruct
            global login_indicator

            if login_indicator == 1
                if user_data(end,image_index+user_data_adjustment) == '0'
                    set(app.AnswerLabel, 'Text', 'Please Answer Question First.');
                else
                    answer_key(app, image_index)
                    app.ShowanswerButton.Enable = "off";
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.NextImageButton = uibutton(app.UIFigure, 'push');
            app.NextImageButton.ButtonPushedFcn = createCallbackFcn(app, @NextImage, true);
            app.NextImageButton.Text = 'Next Image';
            app.PreviousImageButton = uibutton(app.UIFigure, 'push');
            app.PreviousImageButton.ButtonPushedFcn = createCallbackFcn(app, @PrevImage, true);
            app.PreviousImageButton.Text = 'Previous Image';
            app.PleaseloginbeforestartingLabel = uilabel(app.UIFigure);
            app.PleaseloginbeforestartingLabel.Text = 'Please log in before starting';
            app.YourresultwilldisplayhereLabel = uilabel(app.UIFigure);
            app.YourresultwilldisplayhereLabel.Text = 'Your result will display here';
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.FinishExamButton = uibutton(app.UIFigure, 'push');
            app.FinishExamButton.ButtonPushedFcn = createCallbackFcn(app, @FinishExamButtonPushed, true);
            app.FinishExamButton.Text = 'Finish Exam';
            app.UsernameEditFieldLabel = uilabel(app.UIFigure);
            app.UsernameEditFieldLabel.Text = 'Username';
            app.UsernameEditField = uieditfield(app.UIFigure, 'text');
            app.LoginButton = uibutton(app.UIFigure, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LogIn, true);
            app.LoginButton.Text = 'Log in';
            app.second_time_text = uilabel(app.UIFigure);
            app.second_time_text.Text = '';
            app.username_confirm = uilabel(app.UIFigure);
            app.username_confirm.Text = 'Please type in your JHED';
            app.ShowanswerButton = uibutton(app.UIFigure, 'push');
            app.ShowanswerButton.ButtonPushedFcn = createCallbackFcn(app, @ShowanswerButtonPushed, true);
            app.ShowanswerButton.Text = 'Show answer';
            app.AnswerLabel = uilabel(app.UIFigure);
            app.AnswerLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mammology_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end