classdef results_screen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        ByCategoryLabel  matlab.ui.control.Label
        TotalScoreLabel  matlab.ui.control.Label
        ResultsLabel     matlab.ui.control.Label
        CloseButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, scores, totals)
            app.MainApp = mainapp;
            set(app.TotalScoreLabel, 'Text', "Total Score: " + string(scores('total'))+"/"+string(totals('total')))
            keySet = keys(scores);
            category_text = "Category scores:" + newline;
            for i = 1:length(keySet)
                k = keySet{i};
                v = scores(k);
                if strcmp(k,'total') == 0
                    category_text = category_text + k + ": " + string(v) +'/'+string(totals(k))+ newline;
                end
            end
            category_text = category_text + string(totals('unanswered')) + ' questions unanswered';
            set(app.ByCategoryLabel, 'Text', category_text)
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ResultsLabel = uilabel(app.UIFigure);
            app.ResultsLabel.Text = 'Results: ';
            app.TotalScoreLabel = uilabel(app.UIFigure);
            app.TotalScoreLabel.Text = 'Total Score: ';
            app.ByCategoryLabel = uilabel(app.UIFigure);
            app.ByCategoryLabel.Text = 'By Category: ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = results_screen(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end